classdef MCTwithGUIPouriaMotahhari_99171099 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Nmin                       matlab.ui.control.NumericEditField
        MinimumNoTheoreticalStagesNminLabel  matlab.ui.control.Label
        Rmin                       matlab.ui.control.NumericEditField
        RminLabel                  matlab.ui.control.Label
        BottomsB                   matlab.ui.control.NumericEditField
        BottomsBLabel              matlab.ui.control.Label
        DistillateD                matlab.ui.control.NumericEditField
        DistillateDLabel           matlab.ui.control.Label
        ver14ByPouriaMotahhariChESUTLabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        LoadEq                     matlab.ui.control.Button
        OutputsPanel               matlab.ui.container.Panel
        Feed2Stage                 matlab.ui.control.EditField
        Feed2StagefromtopLabel     matlab.ui.control.Label
        NumberofTheoreticalStages  matlab.ui.control.NumericEditField
        NoTheoriticalStagesIncludingReboilerLabel  matlab.ui.control.Label
        Feed1Stage                 matlab.ui.control.NumericEditField
        Feed1StagefromtopLabel     matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        GraphTab                   matlab.ui.container.Tab
        Graph                      matlab.ui.control.UIAxes
        StageCompositionsTab       matlab.ui.container.Tab
        StageCompositions          matlab.ui.control.Table
        Export                     matlab.ui.control.Button
        xBottoms                   matlab.ui.control.NumericEditField
        xBLabel                    matlab.ui.control.Label
        R_On_Rmin                  matlab.ui.control.NumericEditField
        RRminLabel                 matlab.ui.control.Label
        xDistillate                matlab.ui.control.NumericEditField
        xDLabel                    matlab.ui.control.Label
        qval1                      matlab.ui.control.NumericEditField
        q1Label                    matlab.ui.control.Label
        z1                         matlab.ui.control.NumericEditField
        Feed1compositionLabel      matlab.ui.control.Label
        Feed1                      matlab.ui.control.NumericEditField
        Feed1Label                 matlab.ui.control.Label
        qval2                      matlab.ui.control.NumericEditField
        q2Label                    matlab.ui.control.Label
        z2                         matlab.ui.control.NumericEditField
        zF2Label                   matlab.ui.control.Label
        Feed2                      matlab.ui.control.NumericEditField
        F2Label                    matlab.ui.control.Label
        DoubleFeed                 matlab.ui.control.CheckBox
        Calculate                  matlab.ui.control.Button
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        F1 % Description
        zF1 % Description
        q1 % Description
        F2 % Description
        zF2 % Description
        q2 % Description
        Reflux_ratio % Description
        xD % Description
        xB % Description
        xe % Description
        ye % Description
        Rm % Description
        Nm % Description
        D % Description
        B % Description
        F1stage % Description
        F2stage % Description
        NTP % Description
        stage_comp_x % Description
        stage_comp_y % Description
        dif % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dif=0.0001;
            
            app.F1=100;
            app.F2=0;

            app.zF1=0.7;
            app.q1=1;

            app.xB=0.05;
            app.xD=0.95;
            app.Reflux_ratio=1.5;

            app.xDistillate.Limits = [app.zF1+app.dif 1-app.dif];
            app.xBottoms.Limits = [app.dif app.zF1-app.dif];
            app.z2.Limits = [app.xB+app.dif app.zF1-app.dif];
            app.z1.Limits = [app.xB+app.dif app.xD-app.dif];
        end

        % Value changed function: Feed1
        function Feed1ValueChanged(app, event)
            app.F1 = app.Feed1.Value;
        end

        % Value changed function: z1
        function z1ValueChanged(app, event)
            app.zF1 = app.z1.Value;
            app.xDistillate.Limits = [app.zF1+app.dif 1-app.dif];
            app.xBottoms.Limits = [app.dif app.zF1-app.dif];
            app.z2.Limits = [app.xB+app.dif app.zF1-app.dif];
        end

        % Value changed function: qval1
        function qval1ValueChanged(app, event)
            app.q1 = app.qval1.Value;
        end

        % Value changed function: Feed2
        function Feed2ValueChanged(app, event)
            app.F2 = app.Feed2.Value;
        end

        % Value changed function: z2
        function z2ValueChanged(app, event)
            app.zF2 = app.z2.Value;
            app.xBottoms.Limits = [app.dif app.zF1-app.dif];
            app.xBottoms.Limits = [app.dif app.zF2-app.dif];
            app.z1.Limits = [app.zF2+app.dif app.xD-app.dif];
        end

        % Value changed function: qval2
        function qval2ValueChanged(app, event)
            app.q2 = app.qval2.Value;
        end

        % Value changed function: xBottoms
        function xBottomsValueChanged(app, event)
            app.xB = app.xBottoms.Value;
            app.z1.Limits = [app.xB+app.dif app.xD-app.dif];
            app.z2.Limits = [app.xB+app.dif app.zF1-app.dif];
        end

        % Value changed function: xDistillate
        function xDistillateValueChanged(app, event)
            app.xD = app.xDistillate.Value;
            app.z1.Limits = [app.xB+app.dif app.xD-app.dif];
        end

        % Value changed function: R_On_Rmin
        function R_On_RminValueChanged(app, event)
            app.Reflux_ratio = app.R_On_Rmin.Value;
        end

        % Value changed function: DoubleFeed
        function DoubleFeedValueChanged(app, event)
            value = app.DoubleFeed.Value;
            
            if value==false
                app.Feed2.Enable = 'off';
                app.z2.Enable = 'off';
                app.qval2.Enable = 'off';
                app.Feed2.Value = 0;
                app.z2.Value = app.xB;
                app.qval2.Value = 0;

                app.F2=0;
                app.q2=0;
                app.zF2=0;
            else
                app.Feed2.Enable = 'on';
                app.z2.Enable = 'on';
                app.qval2.Enable = 'on';
            end
        end

        % Button pushed function: LoadEq
        function LoadEqButtonPushed(app, event)
            [file,path]=uigetfile('.txt');
            VLE=importdata( convertCharsToStrings([path file]) );
            app.xe=VLE(:,1);
            app.ye=VLE(:,2);

            if isempty(VLE)==0
                app.LoadEq.BackgroundColor = [0 1 0];
            end

            plot(app.Graph,app.xe,app.ye,"B")

            app.Export.Enable = 'on';
            app.Calculate.Enable = 'on';
        end

        % Button pushed function: Calculate
        function CalculateButtonPushed(app, event)
            clc
            syms x
            %% Pouria Motahhari (student ID: 99171099)

            %Unit Operations Project with GUI


            %#################################################
            %########Data Inputs Handlings Part ##########
            %feed1:
            if isempty(app.q1)
                app.q1=0;
            end
            
            %feed2:
            app.q2 = app.qval2.Value;
            app.zF2 = app.z2.Value;

            if isempty(app.q2) || app.q2==0
                app.q2=0;
            end
            if isempty(app.zF2) || app.zF2==0
                app.zF2=0;
            end
            
            if isempty(app.F2) || app.F2==0
                app.zF2=app.zF1;
                app.q2=app.q1;
            end
            
            if app.zF2==app.zF1
                app.q1=(app.q2+app.q1)/2;
                app.q2=app.q1;
            end


            %###########################################################
            %################# Equillibrium Curve Part #################
            %Equillibrium Curve fitting:
            eq_curve=fit(app.xe,app.ye,'poly5');

            eq_curve_sym=subs(str2sym(formula(eq_curve)), ...
                coeffnames(eq_curve), ...
                num2cell(coeffvalues(eq_curve).')); %for fitted polynomial curve equation


            %#########################################################################
            %################# Distillate and Bottoms Flow Rate Part #################
            %Mass Balance Solve for D & W: (A_solve*X_solve=C_solve) "2 equations, 2 unknowns"
            A_solve=[1 1
                    app.xD app.xB];
            C_solve=[app.F1+app.F2
                    app.F1*app.zF1+app.F2*app.zF2];
            X_solve=A_solve\C_solve;
            
            app.D=X_solve(1);
            app.B=X_solve(2);


            %######################################################################
            %################# Feed-Lines(q-lines) Equations Part #################
            if app.zF2>app.zF1
                swap(app.zF1,app.zF2)
                swap(app.q1,app.q2)
                swap(app.F1,app.F2)
            end

            %feed-lines(q-lines) equations:
            feedline1= x*app.q1/(app.q1-1) -app.zF1/(app.q1-1);
            feedline2= x*app.q2/(app.q2-1) -app.zF2/(app.q2-1);


            %#########################################################
            %################# Nmin Calculation Part #################
            %Nmin calculation by assuming the rectifying section line is line x=y (Reflux-->inf)
            min_stage_comp_x=[ vpasolve(eq_curve_sym==app.xD,[0,1]) ]; %vpasolve(eq_curve_sym=xD,[0,1]) is the x value of first stage
            min_stage_comp_y=[app.xD]; %y value of first stage = xD (or yD)
            
            while min_stage_comp_x(end) > app.xB
                min_stage_comp_y(end+1)= min_stage_comp_x(end); %y value of next stage added to array y's
                min_stage_comp_x(end+1)= vpasolve( eq_curve_sym==min_stage_comp_y(end) ,[0,1]); %x value of next stage added to array x'es
            end
            app.Nm=length( min_stage_comp_y ); %  (with reboiler)

            %to calculate Nmin float part we can use this equation:
            %lastx=(min_stage_comp_x(end-1)-xB)/(min_stage_comp_x(end-1)-min_stage_comp_x(end));


            %########################################################
            %#################Rmin Calculation Part #################
            %Rmin calculation by feed-line1, equillibrium-curve and rectifying-section-line intersection:
            if app.q1==1
                x_intersect_min= app.zF1; %if q1 equals 1, feedline1 will be a vertical line
            else
                x_intersect_min= vpasolve(feedline1==eq_curve_sym,[0,1]); %q-line and eqillibrium_curve x intersection
            end
            y_intersect_min= subs(eq_curve_sym,x_intersect_min);
            
            %minimum rectifying section line slope= Rmin/(Rmin+1)= (yD-y_intersect)/(xD-x_intersect):
            app.Rm=double( (app.xD-y_intersect_min) / (y_intersect_min-x_intersect_min) ); %double command= symbolic to numeric
            
            
            %====Correction for Rmin (if the distillation column runs on double feed)====
            if app.F2~=0

                %middle section operating line equation:
                %equation of this operating line is: y= x*(L'/G') + (D*xD-F1*zF1)/(G')     ;slope=L'/G'
                Lmin = app.Rm*app.D;
                Gmin = Lmin+ app.D;
                L_prime_min = app.q1*app.F1+ Lmin;   % q1= (L'-L)/F1
                G_prime_min = Gmin+ app.F1*(app.q1-1);  % 1-q1= (G-G')/F1
                
                mid_feeds_operating_line_min= x*(L_prime_min/G_prime_min) + (app.D*app.xD-app.F1*app.zF1)/G_prime_min ;
                
                %minimum middle section line and feedline2 intersection coordinates:
                if app.q2==1
                    x_intersect_min2= app.zF2; %if q1 equals 1, feedline1 will be a vertical line
                else
                    x_intersect_min2= double( vpasolve( feedline2==mid_feeds_operating_line_min ,[0,1]) ); %q-line and eqillibrium_curve x intersection
                end
                y_intersect_min2= double( subs(mid_feeds_operating_line_min,x_intersect_min2) );
                
                
                if y_intersect_min2> double( subs(eq_curve_sym,x_intersect_min2) )
                
                    if app.q2==1
                    x_intersect_min= app.zF2; %if q1 equals 1, feedline1 will be a vertical line
                    else
                    x_intersect_min= vpasolve(feedline2==eq_curve_sym,[0,1]); %q-line and eqillibrium_curve x intersection
                    end
                    y_intersect_min= subs(eq_curve_sym,x_intersect_min);
                
                    %now that we have the coordinates of feedline2 and equillibruim curve
                    %intersect (rectifying section line), we can calculate new G_bar , G_prime (from q2)
                    %and G (from q1) in minimum reflux condition:
                
                    %stripping section interpole = (B*xB/G_bar) (based on 2 points of (xB,xB) and
                    %feedline2 and eq_curve intersect: 
                    G_bar_min= -(app.B*app.xB)/( app.xB- app.xB* (y_intersect_min-app.xB)/(x_intersect_min-app.xB) );
                
                    %new G_prime_min calculation by q2 definition:
                    G_prime_min= G_bar_min+ app.F2*(1-app.q2);
                
                    %new G_min calculation by q1 definition:
                    G_min= G_prime_min+ app.F1*(1-app.q1);
                
                    %new minimum reflux calculation by new G_min:
                    app.Rm= G_min/app.D -1;
                
                end
            end
            
            %================== End of correction for double-feed Rmin ==================


            %########################################################
            %################# Operating Lines Part #################
            %-------------------rectifying section line equation---------------------
            Reflux=app.Rm*app.Reflux_ratio;
            rectifying=x*Reflux/(Reflux+1) + app.xD/(Reflux+1);
            
            %rectifying section line and feedline1 intersection coordinates:
            if app.q1==1
                x_intersect1= app.zF1; %if q1 equals 1, feedline1 will be a vertical line
            else
                x_intersect1= double( vpasolve(feedline1==rectifying,[0,1]) ); %q-line and eqillibrium_curve x intersection
            end
            y_intersect1= double( subs(rectifying,x_intersect1) );
            
            
            %--------------------middle section operating line equation--------------------
            %equation of this operating line is: y= x*(L'/G') + (D*xD-F1*zF1)/(G')     ;slope=L'/G'
            L=Reflux*app.D;
            G=L+app.D;
            L_prime=app.q1*app.F1+L;   % q1= (L'-L)/F1
            G_prime=G+app.F1*(app.q1-1);  % 1-q1= (G-G')/F1
            
            mid_feeds_operating_line= x*(L_prime/G_prime) + (app.D*app.xD-app.F1*app.zF1)/G_prime ;
            
            %middle section line and feedline2 intersection coordinates:
            if app.q2==1
                x_intersect2= app.zF2; %if q1 equals 1, feedline1 will be a vertical line
            else
                x_intersect2= double( vpasolve( feedline2==mid_feeds_operating_line ,[0,1]) ); %q-line and eqillibrium_curve x intersection
            end
            y_intersect2= double( subs(mid_feeds_operating_line,x_intersect2) );
            
            %----------------------stripping section line equation--------------------------
            %from the coordination of 2 points (xB,xB) & (x_intersect2,y_intersect2)
            stripping=(x-app.xB)*(y_intersect2-app.xB)/(x_intersect2-app.xB)+app.xB;


            %########################################################
            %################# NTP Calculation Part #################
            %theoretical stages calculation by adding stage compositions in a while loop

            %rectifying section:
            app.stage_comp_x=[ vpasolve(eq_curve_sym==app.xD,[0,1]) ]; %vpasolve(eq_curve_sym=xD,[0,1]) is the x value of first stage
            app.stage_comp_y=[app.xD]; %y value of first stage = xD (or yD)
            
            %stages drawer array plotting agent: 
            stage_drawer_x=[app.xD, app.stage_comp_x];
            stage_drawer_y=[app.xD, app.stage_comp_y];
            
            
            while app.stage_comp_x(end) > x_intersect1
                app.stage_comp_y(end+1)= double( subs( rectifying,app.stage_comp_x(end) ) ); %y value of next stage added to array y's
                app.stage_comp_x(end+1)= vpasolve( eq_curve_sym==app.stage_comp_y(end) ,[0,1]); %x value of next stage added to array x'es
            
                stage_drawer_x=[stage_drawer_x, app.stage_comp_x(end-1), app.stage_comp_x(end)];
                stage_drawer_y=[stage_drawer_y, app.stage_comp_y(end), app.stage_comp_y(end)];
            end
            
            feed_stage1= length( app.stage_comp_y ); %feed stage for feed 1
            

            %middle (between 2 feeds) section:
            while app.stage_comp_x(end) > x_intersect2
                app.stage_comp_y(end+1)= double( subs( mid_feeds_operating_line,app.stage_comp_x(end) ) ); %y value of next stage added to array y's
                app.stage_comp_x(end+1)= vpasolve( eq_curve_sym==app.stage_comp_y(end) ,[0,1]); %x value of next stage added to array x'es
            
                stage_drawer_x=[stage_drawer_x, app.stage_comp_x(end-1), app.stage_comp_x(end)];
                stage_drawer_y=[stage_drawer_y, app.stage_comp_y(end), app.stage_comp_y(end)];
            end
            
            feed_stage2= length( app.stage_comp_y ); %feed stage for feed 2
            

            %stripping section:
            while app.stage_comp_x(end) > app.xB
                app.stage_comp_y(end+1)= double( subs( stripping,app.stage_comp_x(end) ) ); %y value of next stage added to array y's
                app.stage_comp_x(end+1)= vpasolve( eq_curve_sym==app.stage_comp_y(end) ,[0,1]); %x value of next stage added to array x'es
            
                stage_drawer_x=[stage_drawer_x, app.stage_comp_x(end-1), app.stage_comp_x(end)];
                stage_drawer_y=[stage_drawer_y, app.stage_comp_y(end), app.stage_comp_y(end)];
            end
            

            %draw last vertical line for reboiler:
            stage_drawer_x=[stage_drawer_x, stage_drawer_x(end)];
            stage_drawer_y=[stage_drawer_y, stage_drawer_x(end)];
            
            %Number of theoretical stages:
            app.NTP=length( app.stage_comp_y );


            %################################################
            %################# OUTPUTS PART #################
            
            %diagram:
            fplot(app.Graph,eq_curve_sym,[0,1], "B") %equillibrium curve
            
            hold (app.Graph,"on")
            
            plot(app.Graph,stage_drawer_x,stage_drawer_y,"r") %stages
            text(app.Graph,app.stage_comp_x(1:end-1) -0.01 ,app.stage_comp_y(1:end-1) +0.01, string(1:numel(app.stage_comp_x)-1), 'FontSize',8 )  %stages numbering 
            text(app.Graph,app.stage_comp_x(end) ,app.stage_comp_y(end)-0.005 , "reboiler" ,'FontSize',6) %reboiler marker
            
            plot(app.Graph,[app.zF1 x_intersect1] ,[app.zF1 y_intersect1],"k") %feed-line1
            plot(app.Graph,[app.zF2 x_intersect2] ,[app.zF2 y_intersect2],"k") %feed-line2
            
            fplot(app.Graph,rectifying,[x_intersect1,app.xD],"g") %rectifying section line
            fplot(app.Graph,mid_feeds_operating_line,[x_intersect2,x_intersect1],"g") %middle section line
            fplot(app.Graph,stripping,[app.xB,x_intersect2],"g") %stripping section line
            plot(app.Graph,app.xe,app.xe,"y","linewidth",1) %x=y line
            
            legend(app.Graph,{'Equillibrium curve','Stages','Feed-line','','','Operating Lines','','x=y'},'Location','southeast')
            
            axis(app.Graph,[0 1 0 1])
            hold (app.Graph,"off")

            %numerical answers:
            app.DistillateD.Value = app.D;
            app.BottomsB.Value= app.B;
            app.Nmin.Value=app.Nm;
            app.Rmin.Value=double(app.Rm);

            app.NumberofTheoreticalStages.Value=app.NTP;
            app.NumberofTheoreticalStages.BackgroundColor = [0.7098 1 0.4706];
            
            if app.zF1==app.zF2
                app.Feed1Stage.Value=feed_stage1;
                app.Feed2Stage.Value= "single-feed";
                app.Feed2Stage.FontSize = 10;
            else 
                app.Feed1Stage.Value=feed_stage1;
                app.Feed2Stage.Value=num2str(feed_stage2);    
            end
            
            %stage compositions:
            app.stage_comp_x = double(app.stage_comp_x);
            app.stage_comp_x = permute( app.stage_comp_x, [2,1] ); %to make the array vertical
            app.stage_comp_y = permute( app.stage_comp_y, [2,1] ); %to make the array vertical
            stage_counter = permute( 1:numel(app.stage_comp_x) , [2,1] ) ;
 
            app.StageCompositions.Data=[num2cell(stage_counter) num2cell( app.stage_comp_x ) num2cell( app.stage_comp_y )];
            app.StageCompositions.Data(end,1) = cellstr("Reboiler"); 

            tableAllignment= uistyle("HorizontalAlignment", "center");
            addStyle(app.StageCompositions, tableAllignment, 'table' , '');
        end

        % Button pushed function: Export
        function ExportButtonPushed(app, event)
            stage_comp= app.stage_comp_x+app.stage_comp_y;
            assignin("base","stage_comp",stage_comp) 
            %this command brings the given variable to main workspace...

            assignin("base","D",app.D)
            assignin("base","B",app.B)
            assignin("base","Nmin",app.Nm)
            assignin("base","Rmin",app.Rm)
            assignin("base","NTP",app.NTP)
            assignin("base","Feed_stage1",app.Feed1Stage.Value)
            if app.zF1~=app.zF2
                assignin("base","Feed_stage2",app.Feed2Stage.Value)
            end

            %*requires R2020a or later* 
            exportgraphics(app.Graph,"McCabe_Thiele_Diagram.pdf","ContentType","vector")
            %this command prints a PDF file of diagram to app's directory
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Continuous_Binary_Fractional_Distillation_EN.png';
            app.Calculate = uibutton(app.UIFigure, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.Calculate.Enable = 'off';
            app.Calculate.Text = 'Calculate';
            app.DoubleFeed = uicheckbox(app.UIFigure);
            app.DoubleFeed.ValueChangedFcn = createCallbackFcn(app, @DoubleFeedValueChanged, true);
            app.DoubleFeed.Text = 'Double-Feed';
            app.F2Label = uilabel(app.UIFigure);
            app.F2Label.Text = 'F2=';
            app.Feed2 = uieditfield(app.UIFigure, 'numeric');
            app.Feed2.Limits = [0 Inf];
            app.Feed2.ValueChangedFcn = createCallbackFcn(app, @Feed2ValueChanged, true);
            app.Feed2.Enable = 'off';
            app.zF2Label = uilabel(app.UIFigure);
            app.zF2Label.Text = 'z2=';
            app.z2 = uieditfield(app.UIFigure, 'numeric');
            app.z2.Limits = [0 1];
            app.z2.ValueChangedFcn = createCallbackFcn(app, @z2ValueChanged, true);
            app.z2.Enable = 'off';
            app.q2Label = uilabel(app.UIFigure);
            app.q2Label.Text = 'q2=';
            app.qval2 = uieditfield(app.UIFigure, 'numeric');
            app.qval2.ValueChangedFcn = createCallbackFcn(app, @qval2ValueChanged, true);
            app.qval2.Enable = 'off';
            app.Feed1Label = uilabel(app.UIFigure);
            app.Feed1Label.Text = 'F1=';
            app.Feed1 = uieditfield(app.UIFigure, 'numeric');
            app.Feed1.Limits = [0.0001 Inf];
            app.Feed1.ValueChangedFcn = createCallbackFcn(app, @Feed1ValueChanged, true);
            app.Feed1.Value = 100;
            app.Feed1compositionLabel = uilabel(app.UIFigure);
            app.Feed1compositionLabel.Text = 'z1=';
            app.z1 = uieditfield(app.UIFigure, 'numeric');
            app.z1.Limits = [0.0001 0.9999];
            app.z1.ValueChangedFcn = createCallbackFcn(app, @z1ValueChanged, true);
            app.z1.Value = 0.7;
            app.q1Label = uilabel(app.UIFigure);
            app.q1Label.Text = 'q1=';
            app.qval1 = uieditfield(app.UIFigure, 'numeric');
            app.qval1.ValueChangedFcn = createCallbackFcn(app, @qval1ValueChanged, true);
            app.qval1.Value = 1;
            app.xDLabel = uilabel(app.UIFigure);
            app.xDLabel.Text = 'xD=';
            app.xDistillate = uieditfield(app.UIFigure, 'numeric');
            app.xDistillate.Limits = [0.0001 0.9999];
            app.xDistillate.ValueChangedFcn = createCallbackFcn(app, @xDistillateValueChanged, true);
            app.xDistillate.Value = 0.95;
            app.RRminLabel = uilabel(app.UIFigure);
            app.RRminLabel.Text = 'R/Rmin=';
            app.R_On_Rmin = uieditfield(app.UIFigure, 'numeric');
            app.R_On_Rmin.Limits = [1.001 Inf];
            app.R_On_Rmin.ValueChangedFcn = createCallbackFcn(app, @R_On_RminValueChanged, true);
            app.R_On_Rmin.Value = 1.5;
            app.xBLabel = uilabel(app.UIFigure);
            app.xBLabel.Text = 'xB=';
            app.xBottoms = uieditfield(app.UIFigure, 'numeric');
            app.xBottoms.Limits = [0.0001 0.9999];
            app.xBottoms.ValueChangedFcn = createCallbackFcn(app, @xBottomsValueChanged, true);
            app.xBottoms.Value = 0.05;
            app.Export = uibutton(app.UIFigure, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Export.Enable = 'off';
            app.Export.Text = 'Export/Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.Graph = uiaxes(app.GraphTab);
            title(app.Graph, 'Distillation Tower''s Graph')
            xlabel(app.Graph, 'x = Mole  fraction of most volatile component in liquid phase')
            ylabel(app.Graph, 'y = Mole fraction of most volatile component in vapor phase')
            zlabel(app.Graph, 'Z')
            app.Graph.XLim = [0 1];
            app.Graph.YLim = [0 1];
            app.Graph.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.StageCompositionsTab = uitab(app.TabGroup);
            app.StageCompositionsTab.Title = 'Stage Compositions';
            app.StageCompositions = uitable(app.StageCompositionsTab);
            app.StageCompositions.ColumnName = {'Stage Number'; 'x_eq'; 'y_eq'};
            app.StageCompositions.RowName = {};
            app.StageCompositions.SelectionType = 'row';
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.Feed1StagefromtopLabel = uilabel(app.OutputsPanel);
            app.Feed1StagefromtopLabel.Text = 'Feed1 Stage (from top)=';
            app.Feed1Stage = uieditfield(app.OutputsPanel, 'numeric');
            app.Feed1Stage.Editable = 'off';
            app.NoTheoriticalStagesIncludingReboilerLabel = uilabel(app.OutputsPanel);
            app.NoTheoriticalStagesIncludingReboilerLabel.Text = {'No. Theoretical Stages '; '(Including Reboiler) NTP='};
            app.NumberofTheoreticalStages = uieditfield(app.OutputsPanel, 'numeric');
            app.NumberofTheoreticalStages.Editable = 'off';
            app.Feed2StagefromtopLabel = uilabel(app.OutputsPanel);
            app.Feed2StagefromtopLabel.Text = 'Feed2 Stage (from top)=';
            app.Feed2Stage = uieditfield(app.OutputsPanel, 'text');
            app.Feed2Stage.Editable = 'off';
            app.Feed2Stage.Value = '0';
            app.LoadEq = uibutton(app.UIFigure, 'push');
            app.LoadEq.ButtonPushedFcn = createCallbackFcn(app, @LoadEqButtonPushed, true);
            app.LoadEq.Text = 'Load Equillibrium Data';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Graphical McCabe-Thiele Method '; 'for Single & Double Feed Distillation'};
            app.ver14ByPouriaMotahhariChESUTLabel = uilabel(app.UIFigure);
            app.ver14ByPouriaMotahhariChESUTLabel.Text = {'ver1.4'; 'By Pouria Motahhari'; '(Ch.E SUT)'};
            app.DistillateDLabel = uilabel(app.UIFigure);
            app.DistillateDLabel.Text = 'Distillate (D)=';
            app.DistillateD = uieditfield(app.UIFigure, 'numeric');
            app.DistillateD.Editable = 'off';
            app.BottomsBLabel = uilabel(app.UIFigure);
            app.BottomsBLabel.Text = 'Bottoms (B)=';
            app.BottomsB = uieditfield(app.UIFigure, 'numeric');
            app.BottomsB.Editable = 'off';
            app.RminLabel = uilabel(app.UIFigure);
            app.RminLabel.Text = 'Minimum Reflux Ratio (Rmin)=';
            app.Rmin = uieditfield(app.UIFigure, 'numeric');
            app.Rmin.Editable = 'off';
            app.MinimumNoTheoreticalStagesNminLabel = uilabel(app.UIFigure);
            app.MinimumNoTheoreticalStagesNminLabel.Text = 'Minimum Theo. Stages (Nmin)=';
            app.Nmin = uieditfield(app.UIFigure, 'numeric');
            app.Nmin.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MCTwithGUIPouriaMotahhari_99171099

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end